classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TestingperformanceTab       matlab.ui.container.Tab
        LoadfileButton              matlab.ui.control.Button
        NoisereductionSpinnerLabel  matlab.ui.control.Label
        NoisereductionSpinner       matlab.ui.control.Spinner
        PacketlengthSpinnerLabel    matlab.ui.control.Label
        PacketlengthSpinner         matlab.ui.control.Spinner
        SamplingFreqSpinnerLabel    matlab.ui.control.Label
        SamplingFreqSpinner         matlab.ui.control.Spinner
        RSBSpinnerLabel             matlab.ui.control.Label
        RSBSpinner                  matlab.ui.control.Spinner
        Spectrogram                 matlab.ui.control.UIAxes
        PlayPauseButton             matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        ColorDropDownLabel          matlab.ui.control.Label
        ColorDropDown               matlab.ui.control.DropDown
        ButtonGroup                 matlab.ui.container.ButtonGroup
        OriginalButton              matlab.ui.control.ToggleButton
        NoisedButton                matlab.ui.control.ToggleButton
        FilteredButton              matlab.ui.control.ToggleButton
        NoiseSettingsLabel          matlab.ui.control.Label
        FilteringSettingsLabel      matlab.ui.control.Label
        WindowDropDownLabel         matlab.ui.control.Label
        WindowDropDown              matlab.ui.control.DropDown
        LoadingSignalLabel          matlab.ui.control.Label
        SignaltoplayLabel_2         matlab.ui.control.Label
    end


    properties (Access = private)
        signal          % line matrix holding loaded signal
        signal_noise    % Signal with noise
        signal_filt     % Signal filtered
        sigma_noise2    % Variance of noise
        window_type     % The window chosen by user
        playing = 0;    % If audio is played 1 if paused 2
        player;
        changed = true;    % equals to 1 if settings changed
    end

    methods (Access = private)
    
        function window = window_methods(app)
            pkt_len = app.PacketlengthSpinner.Value;
            switch app.WindowDropDown.Value
                case 'Hamming'
                    window = hamming(pkt_len).';
                case 'Hann'
                    window = hann(pkt_len).';
                case 'Rectangle'
                    window = ones(1, pkt_len);
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            data = struct2cell(load(strcat(path, file)));
            app.signal = data{1}.';
            app.changed = true;
        end

        % Button pushed function: PlayPauseButton
        function PlayPauseButtonValueChanged(app, event)
            
            if (isempty(app.signal))
                LoadfileButtonPushed(app, event);
            end
            if app.changed == true 
                if app.playing ~= 0
                    stop(app.player);
                    app.playing = 0;
                end
                
                pkt_len = app.PacketlengthSpinner.Value;
                nfft = 2.^nextpow2(pkt_len);
                fe =  app.SamplingFreqSpinner.Value;
                
                switch app.ButtonGroup.SelectedObject.Text
                    case 'Original'
                        [S,F,T] = spectrogram(app.signal, pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                        imagesc(app.Spectrogram, T, F, log(abs(S)));
                        app.Spectrogram.YLim = [0 (fe/2)];
                        app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                        app.Spectrogram.XLim = [1 floor(length(app.signal)/fe)];
                        app.Spectrogram.XLabel.String = 'Time (s)';
                        app.Spectrogram.YDir = 'normal';
                        app.Spectrogram.Title.String = 'Spectrogramm of original signal';
                        
                        app.player = audioplayer(app.signal/max(abs(app.signal)), fe);
                    case 'Noised'
                        %% Adding noise to signal
                        rsb = app.RSBSpinner.Value;
                        [app.signal_noise, app.sigma_noise2] = ajout_bruit(rsb, app.signal);
                        
                        [S,F,T] = spectrogram(app.signal_noise/max(abs(app.signal_noise)), pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                        imagesc(app.Spectrogram, T, F, log(abs(S)));
                        app.Spectrogram.YLim = [0 (fe/2)];
                        app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                        app.Spectrogram.XLim = [1 floor(length(app.signal_noise)/fe)];
                        app.Spectrogram.XLabel.String = 'Time (s)';
                        app.Spectrogram.YDir = 'normal';
                        app.Spectrogram.Title.String = 'Spectrogramm of noised signal';
                        
                        app.player = audioplayer(app.signal_noise/max(abs(app.signal_noise)), fe);
                        
                    case 'Filtered'
                        %% Adding noise to signal
                        rsb = app.RSBSpinner.Value;
                        noise_level = app.NoisereductionSpinner.Value;
                        [app.signal_noise, app.sigma_noise2] = ajout_bruit(rsb, app.signal);
                        
                        %% Filtering the signal

                        window = window_methods(app);
                        app.signal_filt = filter_signal(app.signal_noise, pkt_len, window, app.sigma_noise2, noise_level);
                        app.signal_filt = app.signal_filt / max(abs(app.signal_filt));

                        [S,F,T] = spectrogram(app.signal_filt, pkt_len, .5*pkt_len, nfft, fe, 'yaxis');
                        imagesc(app.Spectrogram, T, F, log(abs(S)));
                        app.Spectrogram.YLim = [0 (fe/2)];
                        app.Spectrogram.YLabel.String = 'Frequency (Hz)';
                        app.Spectrogram.XLim = [1 floor(length(app.signal_filt)/fe)];
                        app.Spectrogram.XLabel.String = 'Time (s)';
                        app.Spectrogram.YDir = 'normal';
                        app.Spectrogram.Title.String = 'Spectrogramm of filtered signal';
                        
                        %% Starting player
                        app.player = audioplayer(app.signal_filt, fe);
                end
                app.changed = false;
            end
            
            if app.playing == 0     % Nothing is playing                  
                play(app.player);
                app.playing = 1;
            elseif app.playing == 1 % Something is playing so we pause
                app.playing = 2;
                pause(app.player);
            else
                app.playing = 1;    % player is paused so we resume
                resume(app.player);
            end
        end

        % Button pushed function: StopButton
        function StopButtonValueChanged(app, event)
            if app.playing ~= 0
                stop(app.player);
                app.playing = 0;
            end
        end

        % Callback function: ButtonGroup, ColorDropDown, 
        % NoisereductionSpinner, PacketlengthSpinner, RSBSpinner, 
        % SamplingFreqSpinner, WindowDropDown
        function ParameterValuesChanged(app, event)
            app.changed = true;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TestingperformanceTab = uitab(app.TabGroup);
            app.TestingperformanceTab.Title = 'Testing performance';
            app.LoadfileButton = uibutton(app.TestingperformanceTab, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Icon = 'Load_file.png';
            app.LoadfileButton.Text = 'Load file';
            app.NoisereductionSpinnerLabel = uilabel(app.TestingperformanceTab);
            app.NoisereductionSpinnerLabel.Text = 'Noise reduction';
            app.NoisereductionSpinner = uispinner(app.TestingperformanceTab);
            app.NoisereductionSpinner.Step = 0.25;
            app.NoisereductionSpinner.Limits = [0 Inf];
            app.NoisereductionSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.NoisereductionSpinner.Value = 3;
            app.PacketlengthSpinnerLabel = uilabel(app.TestingperformanceTab);
            app.PacketlengthSpinnerLabel.Text = 'Packet length';
            app.PacketlengthSpinner = uispinner(app.TestingperformanceTab);
            app.PacketlengthSpinner.Step = 2;
            app.PacketlengthSpinner.Limits = [0 500];
            app.PacketlengthSpinner.ValueDisplayFormat = '%11g';
            app.PacketlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.PacketlengthSpinner.Value = 400;
            app.SamplingFreqSpinnerLabel = uilabel(app.TestingperformanceTab);
            app.SamplingFreqSpinnerLabel.Text = 'Sampling Freq';
            app.SamplingFreqSpinner = uispinner(app.TestingperformanceTab);
            app.SamplingFreqSpinner.Step = 0.25;
            app.SamplingFreqSpinner.Limits = [0 Inf];
            app.SamplingFreqSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.SamplingFreqSpinner.Value = 8000;
            app.RSBSpinnerLabel = uilabel(app.TestingperformanceTab);
            app.RSBSpinnerLabel.Text = 'RSB';
            app.RSBSpinner = uispinner(app.TestingperformanceTab);
            app.RSBSpinner.Limits = [0 Inf];
            app.RSBSpinner.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.RSBSpinner.Value = 10;
            app.Spectrogram = uiaxes(app.TestingperformanceTab);
            title(app.Spectrogram, 'Spectrogram of original signal')
            xlabel(app.Spectrogram, 'Time (s)')
            ylabel(app.Spectrogram, 'Frequencies Hz')
            app.PlayPauseButton = uibutton(app.TestingperformanceTab, 'push');
            app.PlayPauseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayPauseButtonValueChanged, true);
            app.PlayPauseButton.Icon = 'PlayPause.png';
            app.PlayPauseButton.Text = 'Play / Pause';
            app.StopButton = uibutton(app.TestingperformanceTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Icon = 'Stop.png';
            app.StopButton.Text = 'Stop';
            app.ColorDropDownLabel = uilabel(app.TestingperformanceTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.TestingperformanceTab);
            app.ColorDropDown.Items = {'White', 'Pink', 'Blue', 'Real'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.ColorDropDown.Value = 'White';
            app.ButtonGroup = uibuttongroup(app.TestingperformanceTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.OriginalButton = uitogglebutton(app.ButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.NoisedButton = uitogglebutton(app.ButtonGroup);
            app.NoisedButton.Text = 'Noised';
            app.FilteredButton = uitogglebutton(app.ButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.NoiseSettingsLabel = uilabel(app.TestingperformanceTab);
            app.NoiseSettingsLabel.Text = 'Noise Settings';
            app.FilteringSettingsLabel = uilabel(app.TestingperformanceTab);
            app.FilteringSettingsLabel.Text = 'Filtering Settings';
            app.WindowDropDownLabel = uilabel(app.TestingperformanceTab);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.TestingperformanceTab);
            app.WindowDropDown.Items = {'Hamming', 'Hann', 'Rectangle'};
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterValuesChanged, true);
            app.WindowDropDown.Value = 'Hamming';
            app.LoadingSignalLabel = uilabel(app.TestingperformanceTab);
            app.LoadingSignalLabel.Text = 'Loading Signal';
            app.SignaltoplayLabel_2 = uilabel(app.TestingperformanceTab);
            app.SignaltoplayLabel_2.Text = 'Signal to play';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end